classdef MFCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MagneticFieldControlSystemUIFigure  matlab.ui.Figure
        MeasureFieldPanel            matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        CartesianCheckBox            matlab.ui.control.CheckBox
        PolarCheckBox                matlab.ui.control.CheckBox
        MeanValuesPanel              matlab.ui.container.Panel
        xLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        zLabel                       matlab.ui.control.Label
        IntLabel                     matlab.ui.control.Label
        DecLabel                     matlab.ui.control.Label
        IncLabel                     matlab.ui.control.Label
        ConfigurationsPanel          matlab.ui.container.Panel
        LabelNumericEditField2       matlab.ui.control.Label
        SampleRate                   matlab.ui.control.NumericEditField
        ModeButtonGroup              matlab.ui.container.ButtonGroup
        SingleButton                 matlab.ui.control.RadioButton
        ContinuousButton             matlab.ui.control.RadioButton
        PlaySoundCheckBox            matlab.ui.control.CheckBox
        LabelNumericEditField        matlab.ui.control.Label
        Duration                     matlab.ui.control.NumericEditField
        DeviceconnectionSwitch       matlab.ui.control.ToggleSwitch
        DeviceconnectionSwitchLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        SavePanel                    matlab.ui.container.Panel
        SaveCheckBox                 matlab.ui.control.CheckBox
        FileNameEditFieldLabel       matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
        LabelSwitch                  matlab.ui.control.Label
        MeasureSwitch                matlab.ui.control.Switch
        DeviceRangeDropDownLabel     matlab.ui.control.Label
        DeviceRangeDropDown          matlab.ui.control.DropDown
        ShowCameraButton             matlab.ui.control.StateButton
        SetfieldPanel                matlab.ui.container.Panel
        CoordinatesystemButtonGroup  matlab.ui.container.ButtonGroup
        CartesianButton              matlab.ui.control.RadioButton
        PolarButton                  matlab.ui.control.RadioButton
        Button                       matlab.ui.control.Button
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        zEditFieldLabel              matlab.ui.control.Label
        zEditField                   matlab.ui.control.NumericEditField
        IntEditFieldLabel            matlab.ui.control.Label
        IntEditField                 matlab.ui.control.NumericEditField
        DecEditFieldLabel            matlab.ui.control.Label
        DecEditField                 matlab.ui.control.NumericEditField
        IncEditFieldLabel            matlab.ui.control.Label
        IncEditField                 matlab.ui.control.NumericEditField
        SETButton                    matlab.ui.control.StateButton
        PowerSuppliesPanel           matlab.ui.container.Panel
        zCh3Panel                    matlab.ui.container.Panel
        zCh3DropDown                 matlab.ui.control.DropDown
        COMPORTEditField_5Label      matlab.ui.control.Label
        zCh3COMPORTEditField         matlab.ui.control.NumericEditField
        AddressEditField_3Label      matlab.ui.control.Label
        zCh3AddressEditField         matlab.ui.control.NumericEditField
        PolarityDropDown_3Label      matlab.ui.control.Label
        zCh3PolarityDropDown         matlab.ui.control.DropDown
        yCh2Panel                    matlab.ui.container.Panel
        yCh2DropDown                 matlab.ui.control.DropDown
        COMPORTEditField_4Label      matlab.ui.control.Label
        yCh2COMPORTEditField         matlab.ui.control.NumericEditField
        AddressEditField_2Label      matlab.ui.control.Label
        yCh2AddressEditField         matlab.ui.control.NumericEditField
        PolarityDropDown_2Label      matlab.ui.control.Label
        yCh2PolarityDropDown         matlab.ui.control.DropDown
        xCh1Panel                    matlab.ui.container.Panel
        xCh1DropDown                 matlab.ui.control.DropDown
        COMPORTEditFieldLabel        matlab.ui.control.Label
        xCh1COMPORTEditField         matlab.ui.control.NumericEditField
        AddressEditFieldLabel        matlab.ui.control.Label
        xCh1AddressEditField         matlab.ui.control.NumericEditField
        PolarityDropDownLabel        matlab.ui.control.Label
        xCh1PolarityDropDown         matlab.ui.control.DropDown
        CouplingButtonGroup          matlab.ui.container.ButtonGroup
        VoltageButton                matlab.ui.control.RadioButton
        CurrentButton                matlab.ui.control.RadioButton
        VollimitEditFieldLabel       matlab.ui.control.Label
        VollimitEditField            matlab.ui.control.NumericEditField
        IxEditFieldLabel             matlab.ui.control.Label
        IxEditField                  matlab.ui.control.NumericEditField
        IyEditFieldLabel             matlab.ui.control.Label
        IyEditField                  matlab.ui.control.NumericEditField
        IzEditFieldLabel             matlab.ui.control.Label
        IzEditField                  matlab.ui.control.NumericEditField
        setbycurrentButton           matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        STOREButton                  matlab.ui.control.Button
        USEButton                    matlab.ui.control.Button
        AccuracyuTEditFieldLabel     matlab.ui.control.Label
        AccuracyuTEditField          matlab.ui.control.NumericEditField
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        FieldsDropDownLabel          matlab.ui.control.Label
        FieldsDropDown               matlab.ui.control.DropDown
        clearButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        session % session of measurements
    end
    
    properties (Access = public)
        lh % Description
        data=[];
        mulFac=10;
        mnm=0; % mean normalized measurements
        chinfo=zeros(3,4); % Description
        cam % live camera
        Ix=[]; Iy=[]; Iz=[]; Iname={};
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('MATLAB functions')
            addpath('..\Circuit Specialists, 364x\MATLAB functions')
            addpath('..\TDK-Lambda, Z160-5\MATLAB functions')
            load('currents.mat')
            app.Ix=currents.Ix; 
            app.Iy=currents.Iy; 
            app.Iz=currents.Iz; 
            app.Iname=currents.Iname;
            app.FieldsDropDown.Items=app.Iname;
        end

        % Callback function
        function CalibrateButtonPushed(app, event)
            
        end

        % Value changed function: CartesianCheckBox
        function CartesianCheckBoxValueChanged(app, event)
            
        end

        % Selection changed function: CoordinatesystemButtonGroup
        function CoordinatesystemButtonGroupSelectionChanged(app, event)
            selectedButton = app.CoordinatesystemButtonGroup.SelectedObject;
            if selectedButton == app.CartesianButton
                app.xEditField.Enable='on';
                app.yEditField.Enable='on';
                app.zEditField.Enable='on';
                app.IntEditField.Enable='off';
                app.DecEditField.Enable='off';
                app.IncEditField.Enable='off';
            else
                app.xEditField.Enable='off';
                app.yEditField.Enable='off';
                app.zEditField.Enable='off';
                app.IntEditField.Enable='on';
                app.DecEditField.Enable='on';
                app.IncEditField.Enable='on';
            end
        end

        % Selection changed function: CouplingButtonGroup
        function CouplingButtonGroupSelectionChanged(app, event)
            selectedButton = app.CouplingButtonGroup.SelectedObject;
            if selectedButton.Text=='Voltage'
                app.VollimitEditFieldLabel.Text='Current limit:';
            else
                app.VollimitEditFieldLabel.Text='Voltage limit:';
            end
        end

        % Value changed function: DeviceRangeDropDown
        function DeviceRangeDropDownValueChanged(app, event)
            value = app.DeviceRangeDropDown.Value;
            app.mulFac=str2num(value)/10;
        end

        % Callback function
        function InitializeButtonPushed(app, event)
            
        end

        % Value changed function: MeasureSwitch
        function MeasureSwitchValueChanged(app, event)
            switch app.MeasureSwitch.Value
                case 'On'
                    if strcmp(app.DeviceconnectionSwitch.Value,'On')
                        switch app.ModeButtonGroup.SelectedObject
                            case app.SingleButton
                                app.MeasureSwitch.Enable = 'Off';
                                app.session.DurationInSeconds = app.Duration.Value;
                                app.session.Rate = app.SampleRate.Value;
                                rm = startForeground(app.session); %raw measurements
                                t=linspace(0,app.Duration.Value,length(rm)); %time vector
                                cla(app.UIAxes)
                                [nm,int,dec,inc]=plotData(t,rm);
                                %                     legend(app.UIAxes,'x','y','z','int')
                                app.MeasureSwitch.Value = 'Off';
                                app.MeasureSwitch.Enable = 'On';
                                %                     app.mnm = mean(nm);
                                %                     app.mnm(4) = mean(int);
                                
                                app.xLabel.Text=['x: ' num2str(mean(nm(:,1)),3) ' ' char(956) 'T'];
                                app.yLabel.Text=['y: ' num2str(mean(nm(:,2)),3) ' ' char(956) 'T'];
                                app.zLabel.Text=['z: ' num2str(mean(nm(:,3)),3) ' ' char(956) 'T'];
                                app.IntLabel.Text=['Int.: ' num2str(mean(int),3) ' ' char(956) 'T'];
                                app.DecLabel.Text=['Dec.: ' num2str(mean(dec),3) ' °'];
                                app.IncLabel.Text=['Inc.: ' num2str(mean(inc),3) ' °'];
                                
                                if app.SaveCheckBox.Value
                                    t=datetime;t.Format='uuMMdd_HHmmss_';
                                    fn=['Measurements\' char(t) app.FileNameEditField.Value '.mat'];
                                    save(fn,'nm','t')
                                    msgbox(['File ' fn ' saved'])
                                end
                                
                            case app.ContinuousButton
                                app.session.DurationInSeconds = 1000;
                                app.session.Rate = app.SampleRate.Value;
                                app.lh = addlistener(app.session,'DataAvailable',@plotContinous);
                                startBackground(app.session);
                                
                        end
                    else
                        errordlg('Device not connected!');
                        app.MeasureSwitch.Value = 'Off';
                    end
                case 'Off'
                    delete(app.session)
                    ModeButtonGroupSelectionChanged(app,'Off')
                    %                  app.DeviceconnectionSwitch.Value='Off';
                    ni_config(app)
            end
            
            function plotContinous(src, event)
                cla(app.UIAxes)
                [nm,int,dec,inc]=plotData(event.TimeStamps,event.Data);
                %              legend(app.UIAxes,'x','y','z','int')
                app.xLabel.Text=['x: ' num2str(mean(nm(:,1)),3) ' ' char(956) 'T'];
                app.yLabel.Text=['y: ' num2str(mean(nm(:,2)),3) ' ' char(956) 'T'];
                app.zLabel.Text=['z: ' num2str(mean(nm(:,3)),3) ' ' char(956) 'T'];
                app.IntLabel.Text=['Int.: ' num2str(mean(int),3) ' ' char(956) 'T'];
                app.DecLabel.Text=['Dec.: ' num2str(mean(dec),3) ' °'];
                app.IncLabel.Text=['Inc.: ' num2str(mean(inc),3) ' °'];
                
            end
            
            function [nm,int,dec,inc]=plotData(t,rm)
                nm=rm.*app.mulFac; %normalized measurements
                int=sqrt(nm(:,1).^2+nm(:,2).^2+nm(:,3).^2);
                dec=atan2d(nm(:,2),nm(:,1));
                inc=asind(nm(:,3)./int);
                
                if app.CartesianCheckBox.Value==1
                    plot(app.UIAxes,t,nm(:,1),'color',[0.55 0.27 0.08],'markersize',15);hold(app.UIAxes,'on')
                    plot(app.UIAxes,t,nm(:,2),'color',[0.47 0.67 0.19],'markersize',15);
                    plot(app.UIAxes,t,nm(:,3),'color',[0.93 0.69 0.13],'markersize',15);
                    plot(app.UIAxes,t,int,'color',[0.49 0.18 0.56],'markersize',15);
                    if app.PolarCheckBox.Value==1
                        %                         yyaxis(app.UIAxes,'right');
                        plot(app.UIAxes,t,dec,'color',[0.85 0.33 0.10],'markersize',15);
                        plot(app.UIAxes,t,inc,'color',[0.30 0.75 0.93],'markersize',15);
                    end
                elseif app.PolarCheckBox.Value==1
                    plot(app.UIAxes,t,int,'color',[0.49 0.18 0.56],'markersize',15);hold(app.UIAxes,'on')
                    %                     yyaxis(app.UIAxes,'right');
                    plot(app.UIAxes,t,dec,'color',[0.85 0.33 0.10],'markersize',15);
                    plot(app.UIAxes,t,inc,'color',[0.30 0.75 0.93],'markersize',15);
                end
                
                if app.PlaySoundCheckBox.Value
                    sound(sin(int)*10)
                end
                
            end
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            switch app.ModeButtonGroup.SelectedObject
                case app.SingleButton
                    app.Duration.Enable='On';
                    app.MeanValuesPanel.Title='Mean Values';
                case app.ContinuousButton
                    app.Duration.Enable='Off';
                    app.MeanValuesPanel.Title='Values';
            end
            
        end

        % Value changed function: SETButton
        function SETButtonValueChanged(app, event)
            value = app.SETButton.Value;
            if value
                app.DeviceconnectionSwitch.Value='Off';ni_config(app);
                if length(instrfind)
                    fclose(instrfind)
                    delete(instrfind)
                end
                
                % power supply setting information:
                switch app.xCh1DropDown.Value
                    case 'Circuit Specialist 3646A'
                        chtype(1)=1;
                    case 'TDK Lambda Z+'
                        chtype(1)=2;
                end
                switch app.yCh2DropDown.Value
                    case 'Circuit Specialist 3646A'
                        chtype(2)=1;
                    case 'TDK Lambda Z+'
                        chtype(2)=2;
                end
                switch app.zCh3DropDown.Value
                    case 'Circuit Specialist 3646A'
                        chtype(3)=1;
                    case 'TDK Lambda Z+'
                        chtype(3)=2;
                end
                switch app.xCh1PolarityDropDown.Value
                    case 'Normal'
                        ch1pol=true;
                    case 'Reverse'
                        ch1pol=false;
                end
                switch app.yCh2PolarityDropDown.Value
                    case 'Normal'
                        ch2pol=true;
                    case 'Reverse'
                        ch2pol=false;
                end
                switch app.zCh3PolarityDropDown.Value
                    case 'Normal'
                        ch3pol=true;
                    case 'Reverse'
                        ch3pol=false;
                end
                
                app.chinfo=[chtype(1), app.xCh1COMPORTEditField.Value, app.xCh1AddressEditField.Value, ch1pol
                    chtype(2), app.yCh2COMPORTEditField.Value, app.yCh2AddressEditField.Value, ch2pol
                    chtype(3), app.zCh3COMPORTEditField.Value, app.zCh3AddressEditField.Value, ch3pol];
                
                % coordinate system transformations
                selectedButton = app.CoordinatesystemButtonGroup.SelectedObject;
                if selectedButton == app.CartesianButton
                    app.IntEditField.Value = norm([app.xEditField.Value,app.yEditField.Value,app.zEditField.Value]);
                    app.DecEditField.Value = atan2d(app.yEditField.Value,app.xEditField.Value);
                    if app.IntEditField.Value
                        app.IncEditField.Value = asind(app.zEditField.Value/app.IntEditField.Value);
                    end
                else
                    app.xEditField.Value = app.IntEditField.Value*cosd(app.IncEditField.Value)*cosd(app.DecEditField.Value);
                    app.yEditField.Value = app.IntEditField.Value*cosd(app.IncEditField.Value)*sind(app.DecEditField.Value);
                    app.zEditField.Value = app.IntEditField.Value*sind(app.IncEditField.Value);
                end
                req_field = [app.xEditField.Value,app.yEditField.Value,app.zEditField.Value];
                accuracy = app.AccuracyuTEditField.Value;
                % SET field:
                [polarities,Iout]=setMagneticField(req_field,accuracy,app.mulFac,app.chinfo,app.CouplingButtonGroup.SelectedObject.Text,app.VollimitEditField.Value);
                % update polarity setting:
                app.xCh1PolarityDropDown.Value=polarities{1}; app.yCh2PolarityDropDown.Value=polarities{2}; app.zCh3PolarityDropDown.Value=polarities{3};
                % update currents values:
                app.IxEditField.Value=Iout(1); app.IyEditField.Value=Iout(2); app.IzEditField.Value=Iout(3);
                % measure:
                app.DeviceconnectionSwitch.Value='On'; ni_config(app); app.MeasureSwitch.Value='On'; MeasureSwitchValueChanged(app);
            else
                app.SETButton.Enable='Off';
                
                % turning off power supplies:
                cs = [find(app.chinfo(:,1)==1)]'; %Circuit Specialist
                tl = [find(app.chinfo(:,1)==2)]'; %TDK Lambda
                for i=cs
                    cmd82(app.chinfo(i,2), app.chinfo(i,3), 0, 1); cmd82(app.chinfo(i,2), app.chinfo(i,3), 0, 0)
                end
                
                load('MATLAB functions\obj.mat','objComPort');
                for i=1:length(tl)
                    tdkLambda_setVoltage(objComPort(i), 0);
                    tdkLambda_setCurrent(objComPort(i), 0);
                    tdkLambda_setOutput(objComPort(i), 0);
                    fclose(objComPort(i))
                end               
                delete(instrfind)
                app.SETButton.Enable='On';
            end
        end

        % Button pushed function: STOREButton
        function STOREButtonPushed(app, event)
            app.Iname{end+1}=app.NameEditField.Value;
            app.Ix(end+1)=app.IxEditField.Value;
            app.Iy(end+1)=app.IyEditField.Value;
            app.Iz(end+1)=app.IzEditField.Value;
            currents=table(app.Iname,app.Ix,app.Iy,app.Iz,'VariableNames',{'Iname','Ix','Iy','Iz'});
            save('currents.mat','currents')
            app.FieldsDropDown.Items=app.Iname;
        end

        % Callback function
        function STOREButton_2Pushed(app, event)
            
        end

        % Value changed function: ShowCameraButton
        function ShowCameraButtonValueChanged(app, event)
           value = app.ShowCameraButton.Value;
           wcl=webcamlist;
           if value
               app.cam=webcam(char(wcl{2}));
               preview(app.cam);
           else
               delete(app.cam);
           end
        end

        % Button pushed function: USEButton
        function USEButtonPushed(app, event)
            idx=find(strcmp(app.Iname,app.FieldsDropDown.Value));
            app.IxEditField.Value=app.Ix(idx);
            app.IyEditField.Value=app.Iy(idx);
            app.IzEditField.Value=app.Iz(idx);
        end

        % Callback function
        function USEButton_2Pushed(app, event)
            app.IxEditField.Value=app.IxEditField_3.Value;
            app.IyEditField.Value=app.IyEditField_3.Value;
            app.IzEditField.Value=app.IzEditField_3.Value;
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.Ix=[]; app.Iy=[]; app.Iz=[]; app.Iname={};
            currents=table(app.Iname,app.Ix,app.Iy,app.Iz,'VariableNames',{'Iname','Ix','Iy','Iz'});
            save('currents.mat','currents')
            app.FieldsDropDown.Items=app.Iname;
        end

        % Value changed function: DeviceconnectionSwitch
        function ni_config(app, event)
            if strcmp(app.DeviceconnectionSwitch.Value,'On')
                app.session = daq.createSession('ni');
                addAnalogInputChannel(app.session, 'cDAQ1Mod1', 'ai1', 'Voltage');
                addAnalogInputChannel(app.session, 'cDAQ1Mod1', 'ai2', 'Voltage');
                addAnalogInputChannel(app.session, 'cDAQ1Mod1', 'ai3', 'Voltage');
                app.Lamp.Color = 'green';
            else
                app.Lamp.Color = 'red';
                delete(app.session)
            end
        end

        % Button pushed function: setbycurrentButton
        function setbycurrentButtonPushed(app, event)
                if length(instrfind)
                    fclose(instrfind)
                    delete(instrfind)
                end
            % power supply setting information:
                switch app.xCh1DropDown.Value
                    case 'Circuit Specialist 3646A'
                        chtype(1)=1;
                    case 'TDK Lambda Z+'
                        chtype(1)=2;
                end
                switch app.yCh2DropDown.Value
                    case 'Circuit Specialist 3646A'
                        chtype(2)=1;
                    case 'TDK Lambda Z+'
                        chtype(2)=2;
                end
                switch app.zCh3DropDown.Value
                    case 'Circuit Specialist 3646A'
                        chtype(3)=1;
                    case 'TDK Lambda Z+'
                        chtype(3)=2;
                end
                switch app.xCh1PolarityDropDown.Value
                    case 'Normal'
                        ch1pol=true;
                    case 'Reverse'
                        ch1pol=false;
                end
                switch app.yCh2PolarityDropDown.Value
                    case 'Normal'
                        ch2pol=true;
                    case 'Reverse'
                        ch2pol=false;
                end
                switch app.zCh3PolarityDropDown.Value
                    case 'Normal'
                        ch3pol=true;
                    case 'Reverse'
                        ch3pol=false;
                end
                
                app.chinfo=[chtype(1), app.xCh1COMPORTEditField.Value, app.xCh1AddressEditField.Value, ch1pol
                    chtype(2), app.yCh2COMPORTEditField.Value, app.yCh2AddressEditField.Value, ch2pol
                    chtype(3), app.zCh3COMPORTEditField.Value, app.zCh3AddressEditField.Value, ch3pol];
            % set field by specified currents
            polarities=setFieldByCurrent([app.IxEditField.Value,app.IyEditField.Value,app.IzEditField.Value],app.chinfo,app.CouplingButtonGroup.SelectedObject.Text,app.VollimitEditField.Value)
            % update polarity setting:
            app.xCh1PolarityDropDown.Value=polarities{1}; app.yCh2PolarityDropDown.Value=polarities{2}; app.zCh3PolarityDropDown.Value=polarities{3};
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagneticFieldControlSystemUIFigure = uifigure;
            app.MagneticFieldControlSystemUIFigure.Name = 'Magnetic Field Control System';
            app.MeasureFieldPanel = uipanel(app.MagneticFieldControlSystemUIFigure);
            app.MeasureFieldPanel.Title = 'Measure Field';
            app.UIAxes = uiaxes(app.MeasureFieldPanel);
            title(app.UIAxes, 'Magnetic Field')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, '\muT')
            app.CartesianCheckBox = uicheckbox(app.MeasureFieldPanel);
            app.CartesianCheckBox.ValueChangedFcn = createCallbackFcn(app, @CartesianCheckBoxValueChanged, true);
            app.CartesianCheckBox.Text = 'Cartesian';
            app.CartesianCheckBox.Value = true;
            app.PolarCheckBox = uicheckbox(app.MeasureFieldPanel);
            app.PolarCheckBox.Text = 'Polar';
            app.MeanValuesPanel = uipanel(app.MeasureFieldPanel);
            app.MeanValuesPanel.Title = 'Mean Values';
            app.xLabel = uilabel(app.MeanValuesPanel);
            app.xLabel.Text = 'x: ';
            app.yLabel = uilabel(app.MeanValuesPanel);
            app.yLabel.Text = 'y: ';
            app.zLabel = uilabel(app.MeanValuesPanel);
            app.zLabel.Text = 'z: ';
            app.IntLabel = uilabel(app.MeanValuesPanel);
            app.IntLabel.Text = 'Int.:';
            app.DecLabel = uilabel(app.MeanValuesPanel);
            app.DecLabel.Text = 'Dec.:';
            app.IncLabel = uilabel(app.MeanValuesPanel);
            app.IncLabel.Text = 'Inc.:';
            app.ConfigurationsPanel = uipanel(app.MeasureFieldPanel);
            app.ConfigurationsPanel.Title = 'Configurations';
            app.LabelNumericEditField2 = uilabel(app.ConfigurationsPanel);
            app.LabelNumericEditField2.Text = 'Sample rate (Hz)';
            app.SampleRate = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.SampleRate.Limits = [1666 50000];
            app.SampleRate.ValueDisplayFormat = '%11.5g';
            app.SampleRate.Value = 2000;
            app.ModeButtonGroup = uibuttongroup(app.ConfigurationsPanel);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.SingleButton = uiradiobutton(app.ModeButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.ModeButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.PlaySoundCheckBox = uicheckbox(app.ModeButtonGroup);
            app.PlaySoundCheckBox.Text = 'Play Sound';
            app.LabelNumericEditField = uilabel(app.ConfigurationsPanel);
            app.LabelNumericEditField.Text = 'Duration (sec)';
            app.Duration = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.Duration.Value = 1;
            app.DeviceconnectionSwitch = uiswitch(app.ConfigurationsPanel, 'toggle');
            app.DeviceconnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ni_config, true);
            app.DeviceconnectionSwitchLabel = uilabel(app.ConfigurationsPanel);
            app.DeviceconnectionSwitchLabel.Text = 'Device connection';
            app.Label = uilabel(app.ConfigurationsPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.ConfigurationsPanel);
            app.SavePanel = uipanel(app.ConfigurationsPanel);
            app.SavePanel.Title = 'Save';
            app.SaveCheckBox = uicheckbox(app.SavePanel);
            app.SaveCheckBox.Text = 'Save?';
            app.FileNameEditFieldLabel = uilabel(app.SavePanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SavePanel, 'text');
            app.FileNameEditField.Value = 'Ex1';
            app.LabelSwitch = uilabel(app.ConfigurationsPanel);
            app.LabelSwitch.Text = 'Measure';
            app.MeasureSwitch = uiswitch(app.ConfigurationsPanel, 'slider');
            app.MeasureSwitch.ValueChangedFcn = createCallbackFcn(app, @MeasureSwitchValueChanged, true);
            app.DeviceRangeDropDownLabel = uilabel(app.ConfigurationsPanel);
            app.DeviceRangeDropDownLabel.Text = 'Device Range';
            app.DeviceRangeDropDown = uidropdown(app.ConfigurationsPanel);
            app.DeviceRangeDropDown.Items = {'100', '250'};
            app.DeviceRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceRangeDropDownValueChanged, true);
            app.DeviceRangeDropDown.Value = '100';
            app.ShowCameraButton = uibutton(app.MeasureFieldPanel, 'state');
            app.ShowCameraButton.ValueChangedFcn = createCallbackFcn(app, @ShowCameraButtonValueChanged, true);
            app.ShowCameraButton.Text = 'Show Camera';
            app.SetfieldPanel = uipanel(app.MagneticFieldControlSystemUIFigure);
            app.SetfieldPanel.Title = 'Set field';
            app.CoordinatesystemButtonGroup = uibuttongroup(app.SetfieldPanel);
            app.CoordinatesystemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CoordinatesystemButtonGroupSelectionChanged, true);
            app.CoordinatesystemButtonGroup.Title = 'Coordinate system';
            app.CartesianButton = uiradiobutton(app.CoordinatesystemButtonGroup);
            app.CartesianButton.Text = 'Cartesian';
            app.CartesianButton.Value = true;
            app.PolarButton = uiradiobutton(app.CoordinatesystemButtonGroup);
            app.PolarButton.Text = 'Polar';
            app.Button = uibutton(app.CoordinatesystemButtonGroup, 'push');
            app.Button.Icon = 'orientation.png';
            app.Button.Text = '';
            app.xEditFieldLabel = uilabel(app.CoordinatesystemButtonGroup);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.CoordinatesystemButtonGroup, 'numeric');
            app.yEditFieldLabel = uilabel(app.CoordinatesystemButtonGroup);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.CoordinatesystemButtonGroup, 'numeric');
            app.zEditFieldLabel = uilabel(app.CoordinatesystemButtonGroup);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.CoordinatesystemButtonGroup, 'numeric');
            app.IntEditFieldLabel = uilabel(app.CoordinatesystemButtonGroup);
            app.IntEditFieldLabel.Text = 'Int.';
            app.IntEditField = uieditfield(app.CoordinatesystemButtonGroup, 'numeric');
            app.IntEditField.Enable = 'off';
            app.DecEditFieldLabel = uilabel(app.CoordinatesystemButtonGroup);
            app.DecEditFieldLabel.Text = 'Dec.';
            app.DecEditField = uieditfield(app.CoordinatesystemButtonGroup, 'numeric');
            app.DecEditField.Enable = 'off';
            app.IncEditFieldLabel = uilabel(app.CoordinatesystemButtonGroup);
            app.IncEditFieldLabel.Text = 'Inc.';
            app.IncEditField = uieditfield(app.CoordinatesystemButtonGroup, 'numeric');
            app.IncEditField.Enable = 'off';
            app.SETButton = uibutton(app.SetfieldPanel, 'state');
            app.SETButton.ValueChangedFcn = createCallbackFcn(app, @SETButtonValueChanged, true);
            app.SETButton.Text = 'SET!';
            app.PowerSuppliesPanel = uipanel(app.SetfieldPanel);
            app.PowerSuppliesPanel.Title = 'Power Supplies';
            app.zCh3Panel = uipanel(app.PowerSuppliesPanel);
            app.zCh3Panel.Title = 'z - Ch3';
            app.zCh3DropDown = uidropdown(app.zCh3Panel);
            app.zCh3DropDown.Items = {'Circuit Specialist 3646A', 'TDK Lambda Z+'};
            app.zCh3DropDown.Value = 'TDK Lambda Z+';
            app.COMPORTEditField_5Label = uilabel(app.zCh3Panel);
            app.COMPORTEditField_5Label.Text = 'COM PORT';
            app.zCh3COMPORTEditField = uieditfield(app.zCh3Panel, 'numeric');
            app.zCh3COMPORTEditField.Value = 7;
            app.AddressEditField_3Label = uilabel(app.zCh3Panel);
            app.AddressEditField_3Label.Text = 'Address';
            app.zCh3AddressEditField = uieditfield(app.zCh3Panel, 'numeric');
            app.zCh3AddressEditField.Value = 3;
            app.PolarityDropDown_3Label = uilabel(app.zCh3Panel);
            app.PolarityDropDown_3Label.Text = 'Polarity';
            app.zCh3PolarityDropDown = uidropdown(app.zCh3Panel);
            app.zCh3PolarityDropDown.Items = {'Normal', 'Reverse'};
            app.zCh3PolarityDropDown.Value = 'Normal';
            app.yCh2Panel = uipanel(app.PowerSuppliesPanel);
            app.yCh2Panel.Title = 'y - Ch2';
            app.yCh2DropDown = uidropdown(app.yCh2Panel);
            app.yCh2DropDown.Items = {'Circuit Specialist 3646A', 'TDK Lambda Z+'};
            app.yCh2DropDown.Value = 'TDK Lambda Z+';
            app.COMPORTEditField_4Label = uilabel(app.yCh2Panel);
            app.COMPORTEditField_4Label.Text = 'COM PORT';
            app.yCh2COMPORTEditField = uieditfield(app.yCh2Panel, 'numeric');
            app.yCh2COMPORTEditField.Value = 5;
            app.AddressEditField_2Label = uilabel(app.yCh2Panel);
            app.AddressEditField_2Label.Text = 'Address';
            app.yCh2AddressEditField = uieditfield(app.yCh2Panel, 'numeric');
            app.yCh2AddressEditField.Value = 2;
            app.PolarityDropDown_2Label = uilabel(app.yCh2Panel);
            app.PolarityDropDown_2Label.Text = 'Polarity';
            app.yCh2PolarityDropDown = uidropdown(app.yCh2Panel);
            app.yCh2PolarityDropDown.Items = {'Normal', 'Reverse'};
            app.yCh2PolarityDropDown.Value = 'Normal';
            app.xCh1Panel = uipanel(app.PowerSuppliesPanel);
            app.xCh1Panel.Title = 'x - Ch1';
            app.xCh1DropDown = uidropdown(app.xCh1Panel);
            app.xCh1DropDown.Items = {'Circuit Specialist 3646A', 'TDK Lambda Z+'};
            app.xCh1DropDown.Value = 'TDK Lambda Z+';
            app.COMPORTEditFieldLabel = uilabel(app.xCh1Panel);
            app.COMPORTEditFieldLabel.Text = 'COM PORT';
            app.xCh1COMPORTEditField = uieditfield(app.xCh1Panel, 'numeric');
            app.xCh1COMPORTEditField.Value = 4;
            app.AddressEditFieldLabel = uilabel(app.xCh1Panel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.xCh1AddressEditField = uieditfield(app.xCh1Panel, 'numeric');
            app.xCh1AddressEditField.Value = 1;
            app.PolarityDropDownLabel = uilabel(app.xCh1Panel);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.xCh1PolarityDropDown = uidropdown(app.xCh1Panel);
            app.xCh1PolarityDropDown.Items = {'Normal', 'Reverse'};
            app.xCh1PolarityDropDown.Value = 'Normal';
            app.CouplingButtonGroup = uibuttongroup(app.SetfieldPanel);
            app.CouplingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CouplingButtonGroupSelectionChanged, true);
            app.CouplingButtonGroup.Title = 'Coupling';
            app.VoltageButton = uiradiobutton(app.CouplingButtonGroup);
            app.VoltageButton.Text = 'Voltage';
            app.CurrentButton = uiradiobutton(app.CouplingButtonGroup);
            app.CurrentButton.Text = 'Current';
            app.CurrentButton.Value = true;
            app.VollimitEditFieldLabel = uilabel(app.CouplingButtonGroup);
            app.VollimitEditFieldLabel.Text = 'Vol. limit:';
            app.VollimitEditField = uieditfield(app.CouplingButtonGroup, 'numeric');
            app.VollimitEditField.Value = 3;
            app.IxEditFieldLabel = uilabel(app.SetfieldPanel);
            app.IxEditFieldLabel.Text = 'Ix';
            app.IxEditField = uieditfield(app.SetfieldPanel, 'numeric');
            app.IyEditFieldLabel = uilabel(app.SetfieldPanel);
            app.IyEditFieldLabel.Text = 'Iy';
            app.IyEditField = uieditfield(app.SetfieldPanel, 'numeric');
            app.IzEditFieldLabel = uilabel(app.SetfieldPanel);
            app.IzEditFieldLabel.Text = 'Iz';
            app.IzEditField = uieditfield(app.SetfieldPanel, 'numeric');
            app.setbycurrentButton = uibutton(app.SetfieldPanel, 'push');
            app.setbycurrentButton.ButtonPushedFcn = createCallbackFcn(app, @setbycurrentButtonPushed, true);
            app.setbycurrentButton.Text = 'set by current';
            app.Label_2 = uilabel(app.SetfieldPanel);
            app.Label_2.Text = '=>';
            app.STOREButton = uibutton(app.SetfieldPanel, 'push');
            app.STOREButton.ButtonPushedFcn = createCallbackFcn(app, @STOREButtonPushed, true);
            app.STOREButton.Text = 'STORE >';
            app.USEButton = uibutton(app.SetfieldPanel, 'push');
            app.USEButton.ButtonPushedFcn = createCallbackFcn(app, @USEButtonPushed, true);
            app.USEButton.Text = '< USE';
            app.AccuracyuTEditFieldLabel = uilabel(app.SetfieldPanel);
            app.AccuracyuTEditFieldLabel.Text = 'Accuracy (uT)';
            app.AccuracyuTEditField = uieditfield(app.SetfieldPanel, 'numeric');
            app.AccuracyuTEditField.Value = 0.5;
            app.NameEditFieldLabel = uilabel(app.SetfieldPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.SetfieldPanel, 'text');
            app.NameEditField.Value = 'f1';
            app.FieldsDropDownLabel = uilabel(app.SetfieldPanel);
            app.FieldsDropDownLabel.Text = 'Fields';
            app.FieldsDropDown = uidropdown(app.SetfieldPanel);
            app.FieldsDropDown.Items = {'Option 1'};
            app.clearButton = uibutton(app.SetfieldPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MFCS

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagneticFieldControlSystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagneticFieldControlSystemUIFigure)
        end
    end
end